classdef testflight < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioPlayerByDanielMizrahiUIFigure  matlab.ui.Figure
        SpeedMeterGauge                 matlab.ui.control.LinearGauge
        SpeedMeterGaugeLabel            matlab.ui.control.Label
        PlaybackVolumeampGauge          matlab.ui.control.SemicircularGauge
        PlaybackVolumeampGaugeLabel     matlab.ui.control.Label
        SampleCurrentTimeEditField_2    matlab.ui.control.EditField
        SampleCurrentTimeEditFieldLabel_2  matlab.ui.control.Label
        DANGERZONELabel                 matlab.ui.control.Label
        SecLabel_3                      matlab.ui.control.Label
        SecLabel_2                      matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SecLabel                        matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        SampleSizeEditField             matlab.ui.control.EditField
        SampleSizeEditFieldLabel        matlab.ui.control.Label
        SampleTimeResolutionEditField   matlab.ui.control.EditField
        SampleTimeResolutionEditFieldLabel  matlab.ui.control.Label
        SampleTimeLengthEditField       matlab.ui.control.EditField
        SampleTimeLengthEditFieldLabel  matlab.ui.control.Label
        SampleCurrentTimeEditField      matlab.ui.control.EditField
        SampleCurrentTimeEditFieldLabel  matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.EditField
        SampleRateEditFieldLabel        matlab.ui.control.Label
        VolumeKnob                      matlab.ui.control.Knob
        VolumeKnobLabel                 matlab.ui.control.Label
        PlaybackSpeedKnob               matlab.ui.control.Knob
        PlaybackSpeedKnobLabel          matlab.ui.control.Label
        MuteSwitch                      matlab.ui.control.ToggleSwitch
        MuteSwitchLabel                 matlab.ui.control.Label
        MuteLamp                        matlab.ui.control.Lamp
        Boost20dBSwitch                 matlab.ui.control.ToggleSwitch
        Boost20dBSwitchLabel            matlab.ui.control.Label
        AudioStatusLamp                 matlab.ui.control.Lamp
        RECLabel                        matlab.ui.control.Label
        StopAudioButton                 matlab.ui.control.Button
        ResumeAudioButton               matlab.ui.control.Button
        PauseAudioButton                matlab.ui.control.Button
        PlayAudioButton                 matlab.ui.control.Button
        LoadFileButton                  matlab.ui.control.Button
        AudioSpectrogram3D              matlab.ui.control.UIAxes
        AudioSpectrogram                matlab.ui.control.UIAxes
        AudioWaveform                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Audio File Class
        %some of the variables are duped because I changed their names and
        % didnt want to change all the code (no time to waste)
        player %audio player class
        fileName    %audio file - file name
        pathName    %audio file - path name
        y           %audio sample data
        Fs          %audio file - sample rate
        yStatic     %audio sample data - backup for resets
        %currentSample
        %flagR       %flag for 
        flagM               %flag for mute status -> to be able to tell other functions mute occurs -> do nothing
        flagS               %flag for speed changed status -> to be able to change volume as well
        flagSTOPPLOTING     %flag to stop plotting red line
        timeCurrent            %when stop button is pressed, saved current time
         
        Output_Signal %left side audio only
        Sample_Rate %frequency sample rate [Hz]
        Sample_Time_Res %dt [seconds]
        Sample_Amount   %block size - total number of samples
        Sample_Time_Length  %[seconds]
        Sample_Time_Vector  %time vector
    end

    methods (Access = private)

        function specPlot3D(app)
            %spectogram 3d graph
            updateProps(app)
            %create spectorgram graph
            [S,F,T] = spectrogram(app.Output_Signal,1024,512,1024,app.Sample_Rate);
            surf(app.AudioSpectrogram3D, T, F, log(abs(S)));
            
            %spectogram graph settings
            set(app.AudioSpectrogram3D,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            axis(app.AudioSpectrogram3D,'tight');
            axis(app.AudioSpectrogram3D,'square');
            colormap(app.AudioSpectrogram3D,'jet');
            shading(app.AudioSpectrogram3D,'interp');
            view(app.AudioSpectrogram3D,135,40)
        end

        function specPlot2D(app)
            %spectogram 2d graph
            updateProps(app)
            
            %create spectorgram graph
            [S,F,T] = spectrogram(app.Output_Signal,1024,512,1024,app.Sample_Rate); %generate a spectogram
            imagesc(app.AudioSpectrogram, T, F, log(abs(S))); %plot the log (spectrum)
            
            %spectogram graph settings
            set(app.AudioSpectrogram,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            axis(app.AudioSpectrogram,'tight');
            axis(app.AudioSpectrogram,'square');
            
            %create a colorbar graph for spectogram
            chandl = colorbar(app.AudioSpectrogram);   
            chandl.Title.String = "Power/Frequency [dB/Hz]";    %set title on right bar because it doesn't show up idk why
            colormap(app.AudioSpectrogram,jet)  %change color to jet theme
        end

        function wavePlot(app)
            %audio wave graph
            updateProps(app)

            plot(app.AudioWaveform,app.Sample_Time_Vector, app.Output_Signal);
            axis(app.AudioWaveform,'tight');
            ylim(app.AudioWaveform,[-1.5 1.5]);
        end

        function updateApp(app)
            wavePlot(app);
            specPlot2D(app);
            specPlot3D(app);
        end


        function timelineGraph(app)
            updateProps(app)

            app.flagSTOPPLOTING =0; %set flag to stop plotting to false

            hold(app.AudioWaveform, 'on' ) %lock prev plotted graph
            line_handle = xline(app.AudioWaveform,0,'Color','r');   %create a red line at the start of the graph
          
            %debug
            %fprintf('\nSample Rate: %g\tSample Time rate: %.4g\tSample Amount: %g\tSample Time Length: %g\t',app.Sample_Rate,app.Sample_Time_Res,app.Sample_Amount,app.Sample_Time_Length);
           
            Loop_Step=floor((0.03*app.Sample_Rate)); %0.03 is avg time from tic toc values
            tic %start internal timer = 0 secs
            for i = 1:Loop_Step:app.Sample_Amount 
                app.timeCurrent = app.Sample_Time_Vector(i); %update current time
                app.PlaybackVolumeampGauge.Value = abs(app.Output_Signal(round(get(app.player,'Current'),2))); %update volume meter
                line_handle.Value = app.timeCurrent; %update location of xline on x axis
                drawnow; %update graph
                
                updateDisplayTime(app);
                if app.flagSTOPPLOTING == 1
                    break
                end
            end

            %debug
            % end_time = toc;
            % fprintf("End Time: %g\tTime Diff: %.4g", end_time,app.Sample_Time_Length-end_time);
            
            hold(app.AudioWaveform, 'off' ) %dont allow changes to this plot
            wavePlot(app) %update wave graph
            app.AudioStatusLamp.Color=[1,0,0];%set audio status lamp to red - standby

        end


        function updateProps(app)
            %updates all variables
            app.Output_Signal=app.y(:,1); %save only left audio channel
            app.Sample_Rate=app.Fs; %frequency sample rate [Hz]
            app.Sample_Time_Res=1/app.Sample_Rate; %dt [seconds]
            app.Sample_Amount=length(app.Output_Signal); %block size - total number of samples
            app.Sample_Time_Length = app.Sample_Amount * app.Sample_Time_Res; %[seconds]
            app.Sample_Time_Vector=(0:app.Sample_Amount-1)*app.Sample_Time_Res; %time vector


        end
        
        function updateAudioText(app)
           %updates all info text boxes in app
            app.SampleRateEditField.Value = num2str(app.Sample_Rate);
            app.SampleTimeResolutionEditField.Value = num2str(app.Sample_Time_Res); 
            app.SampleSizeEditField.Value = num2str(app.Sample_Amount);
            app.SampleTimeLengthEditField.Value = num2str(round(app.Sample_Time_Length,2));
            updateDisplayTime(app); 
        end


        function updateDisplayTime(app)
            %updates Time Related Text Boxes in app
            app.SampleCurrentTimeEditField.Value = num2str(round(app.timeCurrent,2));
            app.SampleCurrentTimeEditField_2.Value = num2str(round(get(app.player,'Current'),2));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: AudioSpectrogram, LoadFileButton
        function LoadFileButtonPushed(app, event)
            [app.fileName, app.pathName] = uigetfile({'*.mp3;*.m4a'},'Pick an Audio file','MultiSelect','off'); %open load file func
            [app.y,app.Fs] = audioread(app.fileName); %read audio data file @y and frequency sampling @fs
            %app.FileNameWindow.Value = app.fileName; %update file name @text box
            app.player = audioplayer(app.y, app.Fs); %set audio player class
            app.yStatic = app.y; %set backup for audio wave
            %  figure(app.AudioPlayerByDanielMizrahiUIFigure);
            app.AudioPlayerByDanielMizrahiUIFigure.Name = "Audio Player: " + app.fileName;

            % 3 plots initial setups + sample rate value
          %  app.SampleRateEditField.Value = num2str(app.Fs); %update value in sampling frequency text box
            updateProps(app)
            updateAudioText(app)
            updateApp(app)
            

        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            play(app.player);%play audio player
            app.AudioStatusLamp.Color=[0,1,0]; %set audio status lamp to green
            app.timeCurrent=0; %reset current time
            updateProps(app) 
            updateAudioText(app)
            timelineGraph(app)

        end

        % Button pushed function: PauseAudioButton
        function PauseAudioButtonPushed(app, event)
            pause(app.player);%pause audio player
            app.AudioStatusLamp.Color=[0.9290, 0.6940, 0.1250]; %set audio status lamp to orange
        end

        % Button pushed function: ResumeAudioButton
        function ResumeAudioButtonPushed(app, event)
            resume(app.player);%resume audio player
           
            app.AudioStatusLamp.Color=[0,1,0]; %set audio status lamp to green
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            stop(app.player);%stop audio player
            app.AudioStatusLamp.Color=[1,0,0];%set audio status lamp to red
            app.flagSTOPPLOTING =1;
        end

        % Value changed function: Boost20dBSwitch
        function Boost20dBSwitchValueChanged(app, event)
            value = app.Boost20dBSwitch.Value;
             if(app.flagM==1) %if mute button is turned on do nothing
                return
            end
            if(strcmp(value,'On'))
                app.y=app.y*1.2; %multiply amplitutde by 1.2 = +20db
                app.player = audioplayer(app.y, app.Fs); %reset audio player class
            %    app.flagR=1; %set flag true
                updateApp(app)
            else
                app.y=app.yStatic; %restore audio to original audio sample
                app.player = audioplayer(app.y, app.Fs); %reset audio player class
            %    app.flagR=0; %set flag false
                updateApp(app)
            end

        end

        % Value changed function: MuteSwitch
        function MuteSwitchValueChanged(app, event)
            value = app.MuteSwitch.Value;
            if(strcmp(value,'On'))
                app.y=app.yStatic *0; %floor audio sample
                app.player = audioplayer(app.y, app.Fs); %reset audio player class
                app.flagM=1;    %set muted flag true
                updateApp(app)  %update graphs
            else
                app.y=app.yStatic;  %restore to original audio sample
                app.player = audioplayer(app.y, app.Fs); %reset audio player class
                app.flagM = 0;  %set muted flag false
                updateApp(app)  %update graphs
            end
        end

        % Value changed function: PlaybackSpeedKnob
        function PlaybackSpeedKnobValueChanged(app, event)
            value = app.PlaybackSpeedKnob.Value;
            if(app.flagM==1) %if mute button is turned on do nothing
                return
            end
            if value == 1
                app.y = app.yStatic;    %reset audio sample
                app.flagS=0;    %set flag speed changed false
            else
                app.y = stretchAudio(app.yStatic,value);    %change audio sample speed
                app.flagS=1;    %set flag speed changed true
            end
            app.player = audioplayer(app.y, app.Fs);    %update audio player class
            updateApp(app)  %update graphs
            updateProps(app)
            updateAudioText(app)
               
            %update the speed gauge
            app.SpeedMeterGauge.Value = value;

        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
            volume  = app.VolumeKnob.Value;
            if(app.flagM==1) %if mute button is turned on do nothing
                return
            end
            if app.flagS == 1
                app.y=app.y *volume;    %multiply the amplitude of audio sample by volume val
            else
                app.y=app.yStatic *volume;  %set to original audio sample
            end
            app.player = audioplayer(app.y, app.Fs); %reset audio player class
            updateApp(app)  %update graphs
            app.PlaybackVolumeampGauge.Limits = [0 volume];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioPlayerByDanielMizrahiUIFigure = uifigure('Visible', 'off');
            app.AudioPlayerByDanielMizrahiUIFigure.Name = 'Audio Player By Daniel Mizrahi';
            app.AudioWaveform = uiaxes(app.AudioPlayerByDanielMizrahiUIFigure);
            title(app.AudioWaveform, 'Waveform')
            xlabel(app.AudioWaveform, 'Time [sec]')
            ylabel(app.AudioWaveform, 'Amplitude')
            zlabel(app.AudioWaveform, 'Z')
            app.AudioWaveform.YLim = [-1.2 1.2];
            app.AudioSpectrogram = uiaxes(app.AudioPlayerByDanielMizrahiUIFigure);
            title(app.AudioSpectrogram, 'Spectrogram')
            xlabel(app.AudioSpectrogram, 'Time [sec]')
            ylabel(app.AudioSpectrogram, 'Frequency [kHz]')
            zlabel(app.AudioSpectrogram, 'Z')
            app.AudioSpectrogram.ButtonDownFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.AudioSpectrogram3D = uiaxes(app.AudioPlayerByDanielMizrahiUIFigure);
            title(app.AudioSpectrogram3D, '3D Spectrogram')
            xlabel(app.AudioSpectrogram3D, 'Time [sec]')
            ylabel(app.AudioSpectrogram3D, 'Frequency [kHz]')
            zlabel(app.AudioSpectrogram3D, 'Z')
            app.LoadFileButton = uibutton(app.AudioPlayerByDanielMizrahiUIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.PlayAudioButton = uibutton(app.AudioPlayerByDanielMizrahiUIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play';
            app.PauseAudioButton = uibutton(app.AudioPlayerByDanielMizrahiUIFigure, 'push');
            app.PauseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PauseAudioButtonPushed, true);
            app.PauseAudioButton.Text = 'Pause';
            app.ResumeAudioButton = uibutton(app.AudioPlayerByDanielMizrahiUIFigure, 'push');
            app.ResumeAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeAudioButtonPushed, true);
            app.ResumeAudioButton.Text = 'Resume';
            app.StopAudioButton = uibutton(app.AudioPlayerByDanielMizrahiUIFigure, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop';
            app.RECLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.RECLabel.Text = 'Status';
            app.AudioStatusLamp = uilamp(app.AudioPlayerByDanielMizrahiUIFigure);
            app.Boost20dBSwitchLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.Boost20dBSwitchLabel.Text = 'Boost +20dB';
            app.Boost20dBSwitch = uiswitch(app.AudioPlayerByDanielMizrahiUIFigure, 'toggle');
            app.Boost20dBSwitch.ValueChangedFcn = createCallbackFcn(app, @Boost20dBSwitchValueChanged, true);
            app.MuteLamp = uilamp(app.AudioPlayerByDanielMizrahiUIFigure);
            app.MuteSwitchLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.MuteSwitchLabel.Text = 'Mute';
            app.MuteSwitch = uiswitch(app.AudioPlayerByDanielMizrahiUIFigure, 'toggle');
            app.MuteSwitch.ValueChangedFcn = createCallbackFcn(app, @MuteSwitchValueChanged, true);
            app.PlaybackSpeedKnobLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.PlaybackSpeedKnobLabel.Text = 'Playback Speed';
            app.PlaybackSpeedKnob = uiknob(app.AudioPlayerByDanielMizrahiUIFigure, 'continuous');
            app.PlaybackSpeedKnob.Limits = [0.5 10];
            app.PlaybackSpeedKnob.MajorTicks = [0.5 1 2.9 4.1 5.3 6.5 7.7 8.9 10];
            app.PlaybackSpeedKnob.ValueChangedFcn = createCallbackFcn(app, @PlaybackSpeedKnobValueChanged, true);
            app.PlaybackSpeedKnob.Value = 1;
            app.VolumeKnobLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.AudioPlayerByDanielMizrahiUIFigure, 'continuous');
            app.VolumeKnob.Limits = [1 10];
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            app.VolumeKnob.Value = 1;
            app.SampleRateEditFieldLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.AudioPlayerByDanielMizrahiUIFigure, 'text');
            app.SampleRateEditField.Editable = 'off';
            app.SampleCurrentTimeEditFieldLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SampleCurrentTimeEditFieldLabel.Text = 'Sample Current Time';
            app.SampleCurrentTimeEditField = uieditfield(app.AudioPlayerByDanielMizrahiUIFigure, 'text');
            app.SampleCurrentTimeEditField.Editable = 'off';
            app.SampleTimeLengthEditFieldLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SampleTimeLengthEditFieldLabel.Text = 'Sample Time Length';
            app.SampleTimeLengthEditField = uieditfield(app.AudioPlayerByDanielMizrahiUIFigure, 'text');
            app.SampleTimeLengthEditField.Editable = 'off';
            app.SampleTimeResolutionEditFieldLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SampleTimeResolutionEditFieldLabel.Text = 'Sample Time Resolution';
            app.SampleTimeResolutionEditField = uieditfield(app.AudioPlayerByDanielMizrahiUIFigure, 'text');
            app.SampleTimeResolutionEditField.Editable = 'off';
            app.SampleSizeEditFieldLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.SampleSizeEditField = uieditfield(app.AudioPlayerByDanielMizrahiUIFigure, 'text');
            app.SampleSizeEditField.Editable = 'off';
            app.HzLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.HzLabel.Text = '[Hz]';
            app.SecLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SecLabel.Text = '[Sec]';
            app.Label_3 = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.Label_3.Text = '[#]';
            app.SecLabel_2 = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SecLabel_2.Text = '[Sec]';
            app.SecLabel_3 = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SecLabel_3.Text = '[Sec]';
            app.DANGERZONELabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.DANGERZONELabel.Text = 'DANGER ZONE!!!!';
            app.SampleCurrentTimeEditFieldLabel_2 = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SampleCurrentTimeEditFieldLabel_2.Text = 'Sample #';
            app.SampleCurrentTimeEditField_2 = uieditfield(app.AudioPlayerByDanielMizrahiUIFigure, 'text');
            app.SampleCurrentTimeEditField_2.Editable = 'off';
            app.PlaybackVolumeampGaugeLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.PlaybackVolumeampGaugeLabel.Text = 'Playback Volume [amp]';
            app.PlaybackVolumeampGauge = uigauge(app.AudioPlayerByDanielMizrahiUIFigure, 'semicircular');
            app.PlaybackVolumeampGauge.Limits = [0 1];
            app.SpeedMeterGaugeLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SpeedMeterGaugeLabel.Text = 'Speed Meter';
            app.SpeedMeterGauge = uigauge(app.AudioPlayerByDanielMizrahiUIFigure, 'linear');
            app.SpeedMeterGauge.Limits = [0.5 10];
            app.SpeedMeterGauge.MajorTicks = [0.5 1 5 10];
            app.SpeedMeterGauge.MajorTickLabels = {'Slow', 'Normal', 'Fast', 'Too Fast', '', '', '', '', '', '', '', '', '', 'g'};
            app.SpeedMeterGauge.Value = 1;
            % Show the figure after all components are created
            app.AudioPlayerByDanielMizrahiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testflight

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioPlayerByDanielMizrahiUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioPlayerByDanielMizrahiUIFigure)
        end
    end
end